classdef leg_switch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label_2                 matlab.ui.control.Label
        ExportButton            matlab.ui.control.Button
        qualityLabel_2          matlab.ui.control.Label
        qualityLabel            matlab.ui.control.Label
        ControlsPanel           matlab.ui.container.Panel
        FilenameEditField       matlab.ui.control.EditField
        FilenameEditFieldLabel  matlab.ui.control.Label
        BadButton               matlab.ui.control.Button
        SwitchButton            matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        LoadStartButton         matlab.ui.control.Button
        switch_flagLabel        matlab.ui.control.Label
        switchLabel             matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        frameLabel              matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vidObj % Description
        currentFrame
        frameCtr
        keypoints
    end
    
    methods (Access = private)
        
        function result = isSwitched(app)
            if app.keypoints.switch(app.frameCtr) == 1
                result = 'SWITCHED';
            else
                result ="not switch";
            end
            app.switch_flagLabel.Text = result;
        end
        
        function applySwitch(app)
            temp = app.keypoints;
            %right to left
            app.keypoints.r_hip_x(app.frameCtr)= temp.l_hip_x(app.frameCtr);
            app.keypoints.r_hip_y(app.frameCtr)= temp.l_hip_y(app.frameCtr);
            app.keypoints.r_knee_x(app.frameCtr)= temp.l_knee_x(app.frameCtr);
            app.keypoints.r_knee_y(app.frameCtr)= temp.l_knee_y(app.frameCtr);
            app.keypoints.r_ankle_x(app.frameCtr)= temp.l_ankle_x(app.frameCtr);
            app.keypoints.r_ankle_y(app.frameCtr)= temp.l_ankle_y(app.frameCtr);
            app.keypoints.r_heel_x(app.frameCtr)= temp.l_heel_x(app.frameCtr);
            app.keypoints.r_heel_y(app.frameCtr)= temp.l_heel_y(app.frameCtr);
            app.keypoints.r_bigtoe_x(app.frameCtr)= temp.l_bigtoe_x(app.frameCtr);
            app.keypoints.r_bigtoe_y(app.frameCtr)= temp.l_bigtoe_y(app.frameCtr);
            app.keypoints.r_smalltoe_x(app.frameCtr)= temp.l_smalltoe_x(app.frameCtr);
            app.keypoints.r_smalltoe_y(app.frameCtr)= temp.l_smalltoe_y(app.frameCtr);
            
            %left to right
            app.keypoints.l_hip_x(app.frameCtr)= temp.r_hip_x(app.frameCtr);
            app.keypoints.l_hip_y(app.frameCtr)= temp.r_hip_y(app.frameCtr);
            app.keypoints.l_knee_x(app.frameCtr)= temp.r_knee_x(app.frameCtr);
            app.keypoints.l_knee_y(app.frameCtr)= temp.r_knee_y(app.frameCtr);
            app.keypoints.l_ankle_x(app.frameCtr)= temp.r_ankle_x(app.frameCtr);
            app.keypoints.l_ankle_y(app.frameCtr)= temp.r_ankle_y(app.frameCtr);
            app.keypoints.l_heel_x(app.frameCtr)= temp.r_heel_x(app.frameCtr);
            app.keypoints.l_heel_y(app.frameCtr)= temp.r_heel_y(app.frameCtr);
            app.keypoints.l_bigtoe_x(app.frameCtr)= temp.r_bigtoe_x(app.frameCtr);
            app.keypoints.l_bigtoe_y(app.frameCtr)= temp.r_bigtoe_y(app.frameCtr);
            app.keypoints.l_smalltoe_x(app.frameCtr)= temp.r_smalltoe_x(app.frameCtr);
            app.keypoints.l_smalltoe_y(app.frameCtr)= temp.r_smalltoe_y(app.frameCtr);
        end
        
        function isBad(app)
            if app.keypoints.badFlag(app.frameCtr) == 1
                text = 'BAD FRAME';
   
            else
                text ="ok";
            end
            app.qualityLabel.Text = text;
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadStartButton
        function LoadStartButtonPushed(app, event)
            filename=app.FilenameEditField.Value
            %filename="p1s1";
            vidfile="data\op_videos\"+filename+"-openpose.avi";
            app.vidObj=VideoReader(vidfile); %change
            %set start frame to 1
            app.currentFrame=read(app.vidObj,1);
            imshow(app.currentFrame,'parent',app.UIAxes);
            app.Label.Text=1 + "/" + app.vidObj.NumFrames;
            app.frameCtr=1;
            csvfile="data\csv\"+filename+".csv";
            app.keypoints = readtable(csvfile); %change
            app.keypoints.switch=zeros([height(app.keypoints),1]);
            app.keypoints.badFlag=zeros([height(app.keypoints),1]);
            isSwitched(app);
            isBad(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.frameCtr < app.vidObj.NumFrames
                app.frameCtr=app.frameCtr+1;
                app.currentFrame=read(app.vidObj,app.frameCtr);
                app.Label.Text=app.frameCtr + "/" + app.vidObj.NumFrames;
                imshow(app.currentFrame,'parent',app.UIAxes);
                isSwitched(app);
               isBad(app);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.frameCtr > 1
                app.frameCtr=app.frameCtr-1;
                app.currentFrame=read(app.vidObj,app.frameCtr);
                app.Label.Text=app.frameCtr + "/" + app.vidObj.NumFrames;
                imshow(app.currentFrame,'parent',app.UIAxes);
                isSwitched(app);
                isBad(app);
            end
        end

        % Callback function
        function SwitchButtonValueChanged(app, event)
            value = app.SwitchButton.Value;
            if value == 1
                applySwitch(app);
                app.keypoints.switch(app.frameCtr) =1;
            end
            if value == 0
                applySwitch(app);
                app.keypoints.switch(app.frameCtr) = 0;
            end
            isSwitched(app);
            
        end

        % Callback function
        function BadButtonValueChanged(app, event)
            value = app.BadButton.Value;
            if value == 1
                app.keypoints.badFlag(app.frameCtr)=1;
            else
                app.keypoints.badFlag(app.frameCtr)=0;
            end
            isBad(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            filename=app.FilenameEditField.Value;
            writetable(app.keypoints,"data\csv\"+filename+"_switched.csv"); %change
            app.Label_2.Text= 'Export Successful!';
        end

        % Button pushed function: SwitchButton
        function SwitchButtonPushed(app, event)
            applySwitch(app);
            app.keypoints.switch(app.frameCtr) =~app.keypoints.switch(app.frameCtr);
            isSwitched(app);
            
        end

        % Button pushed function: BadButton
        function BadButtonPushed(app, event)
            app.keypoints.badFlag(app.frameCtr)=~app.keypoints.badFlag(app.frameCtr);
            isBad(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.frameLabel = uilabel(app.UIFigure);
            app.frameLabel.Text = 'frame #:';
            app.Label = uilabel(app.UIFigure);
            app.switchLabel = uilabel(app.UIFigure);
            app.switchLabel.Text = 'switch:';
            app.switch_flagLabel = uilabel(app.UIFigure);
            app.switch_flagLabel.Text = {'switch_flag'; ''};
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.LoadStartButton = uibutton(app.ControlsPanel, 'push');
            app.LoadStartButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStartButtonPushed, true);
            app.LoadStartButton.Text = {'Load/Start'; ''};
            app.Button = uibutton(app.ControlsPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'>'; ''};
            app.Button_2 = uibutton(app.ControlsPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<';
            app.SwitchButton = uibutton(app.ControlsPanel, 'push');
            app.SwitchButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchButtonPushed, true);
            app.SwitchButton.Text = 'Switch';
            app.BadButton = uibutton(app.ControlsPanel, 'push');
            app.BadButton.ButtonPushedFcn = createCallbackFcn(app, @BadButtonPushed, true);
            app.BadButton.Text = 'Bad';
            app.FilenameEditFieldLabel = uilabel(app.ControlsPanel);
            app.FilenameEditFieldLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.ControlsPanel, 'text');
            app.qualityLabel = uilabel(app.UIFigure);
            app.qualityLabel.Text = 'quality:';
            app.qualityLabel_2 = uilabel(app.UIFigure);
            app.qualityLabel_2.Text = 'quality:';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = leg_switch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end